classdef YasHesaplama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        YasHesaplamaProjesiPanel       matlab.ui.container.Panel
        YkleniyorGauge                 matlab.ui.control.Gauge
        YkleniyorGaugeLabel            matlab.ui.control.Label
        GiriButton                     matlab.ui.control.Button
        MenyeDnButton                  matlab.ui.control.Button
        KapatButton                    matlab.ui.control.Button
        BalanButton                    matlab.ui.control.Button
        BalantLamp                     matlab.ui.control.Lamp
        MenPanel                       matlab.ui.container.Panel
        KaytSilButton                  matlab.ui.control.Button
        KaytGncelleButton              matlab.ui.control.Button
        KaytEkleButton                 matlab.ui.control.Button
        YaHesaplaButton                matlab.ui.control.Button
        KaytlarGsterButton             matlab.ui.control.Button
        KaytlarGstermePanel            matlab.ui.container.Panel
        DateButtonGroup                matlab.ui.container.ButtonGroup
        Button_12                      matlab.ui.control.RadioButton
        Button_11                      matlab.ui.control.RadioButton
        Button_10                      matlab.ui.control.RadioButton
        Button_9                       matlab.ui.control.RadioButton
        Button_8                       matlab.ui.control.RadioButton
        Button_7                       matlab.ui.control.RadioButton
        AgeButtonGroup                 matlab.ui.container.ButtonGroup
        Button_18                      matlab.ui.control.RadioButton
        Button_17                      matlab.ui.control.RadioButton
        Button_16                      matlab.ui.control.RadioButton
        Button_15                      matlab.ui.control.RadioButton
        Button_14                      matlab.ui.control.RadioButton
        Button_13                      matlab.ui.control.RadioButton
        IDButtonGroup                  matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Button_4                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        Button_6                       matlab.ui.control.RadioButton
        DateSearchDatePicker_2         matlab.ui.control.DatePicker
        DateSearchDatePicker_2Label    matlab.ui.control.Label
        AgeSearchEditField_2Label      matlab.ui.control.Label
        AgeSearchEditField_2           matlab.ui.control.NumericEditField
        IDSearchEditField_2            matlab.ui.control.NumericEditField
        IDSearchEditField_2Label       matlab.ui.control.Label
        AraButton                      matlab.ui.control.Button
        IDSwitch                       matlab.ui.control.Switch
        simSwitch                      matlab.ui.control.Switch
        SoyisimSwitch                  matlab.ui.control.Switch
        YaSwitch                       matlab.ui.control.Switch
        DoumTarihiSwitch               matlab.ui.control.Switch
        BurSwitch                      matlab.ui.control.Switch
        DateSearchDatePicker           matlab.ui.control.DatePicker
        DateSearchDatePickerLabel      matlab.ui.control.Label
        ZodiacSearchEditField          matlab.ui.control.EditField
        ZodiacSearchEditFieldLabel     matlab.ui.control.Label
        AgeSearchEditFieldLabel        matlab.ui.control.Label
        BurSwitchLabel                 matlab.ui.control.Label
        LastnameSearchEditField        matlab.ui.control.EditField
        LastnameSearchEditFieldLabel   matlab.ui.control.Label
        DoumTarihiSwitchLabel          matlab.ui.control.Label
        YaSwitchLabel                  matlab.ui.control.Label
        SoyisimSwitchLabel             matlab.ui.control.Label
        simSwitchLabel                 matlab.ui.control.Label
        IDSwitchLabel                  matlab.ui.control.Label
        AgeSearchEditField             matlab.ui.control.NumericEditField
        NameSearchEditField            matlab.ui.control.EditField
        NameSearchEditFieldLabel       matlab.ui.control.Label
        IDSearchEditField              matlab.ui.control.NumericEditField
        IDSearchEditFieldLabel         matlab.ui.control.Label
        KaytlarGetirButton             matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        TabGroup                       matlab.ui.container.TabGroup
        KaytszTab                      matlab.ui.container.Tab
        HesaplaButton                  matlab.ui.control.Button
        GnmzCheckBox                   matlab.ui.control.CheckBox
        BirTarihSeinizDatePicker       matlab.ui.control.DatePicker
        BirTarihSeinizDatePickerLabel  matlab.ui.control.Label
        DoumTarihinizDatePicker        matlab.ui.control.DatePicker
        DoumTarihinizDatePickerLabel   matlab.ui.control.Label
        KaytlTab                       matlab.ui.container.Tab
        HesaplaButton_2                matlab.ui.control.Button
        SeilenIDyegreyaveburhesaplamasyaplacaktrLabel  matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        VeritabannaKaydetCheckBox      matlab.ui.control.CheckBox
        IDDropDown                     matlab.ui.control.DropDown
        IDDropDownLabel                matlab.ui.control.Label
        KaytEklemePanel                matlab.ui.container.Panel
        KaydetButton                   matlab.ui.control.Button
        DoumTarihiDatePicker_2         matlab.ui.control.DatePicker
        DoumTarihiDatePicker_2Label    matlab.ui.control.Label
        SoyadEditField_2               matlab.ui.control.EditField
        SoyadEditField_2Label          matlab.ui.control.Label
        AdEditField_2                  matlab.ui.control.EditField
        AdEditField_2Label             matlab.ui.control.Label
        KaytGncellemePanel             matlab.ui.container.Panel
        SeilenyenitarihegreyaveburotomatikolarakkaydedilecektirLabel  matlab.ui.control.Label
        DoumTarihiCheckBox             matlab.ui.control.CheckBox
        SoyadCheckBox                  matlab.ui.control.CheckBox
        AdCheckBox                     matlab.ui.control.CheckBox
        GirilenIDyegregncellemeyaplacaktrLabel  matlab.ui.control.Label
        IDEditField                    matlab.ui.control.NumericEditField
        IDEditFieldLabel               matlab.ui.control.Label
        GncelleButton                  matlab.ui.control.Button
        DoumTarihiDatePicker           matlab.ui.control.DatePicker
        DoumTarihiDatePickerLabel      matlab.ui.control.Label
        SoyadEditField                 matlab.ui.control.EditField
        SoyadEditFieldLabel            matlab.ui.control.Label
        AdEditField                    matlab.ui.control.EditField
        AdEditFieldLabel               matlab.ui.control.Label
        KaytsilmePanel                 matlab.ui.container.Panel
        GirilenIDyegrekaytsilinecektirLabel  matlab.ui.control.Label
        IDEditField_2                  matlab.ui.control.NumericEditField
        IDEditField_2Label             matlab.ui.control.Label
        SilButton                      matlab.ui.control.Button
        BalantLampLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Connections of database
        SqlConnections
        nameDb = "MatlabProje";
        query1 = "select * from Matlab.dbo.Registrations";

        ActivePanel
        ActiveSwitch

    end
    
    methods (Access = public)
        function zodiac = FindZodiac(~,Month,Day)
            if (Month == 1 && Day >= 20) || (Month == 2 && Day <= 18)
                zodiac = "Kova";
            elseif (Month == 2 && Day >= 19) || (Month == 3 && Day <= 20)
                zodiac = "Balık";
            elseif (Month == 3 && Day >= 21) || (Month == 4 && Day <= 19)
                zodiac = "Koç";
            elseif (Month == 4 && Day >= 20) || (Month == 5 && Day <= 20)
                zodiac = "Boğa";
            elseif (Month == 5 && Day >= 21) || (Month == 6 && Day <= 20)
                zodiac = "İkizler";
            elseif (Month == 6 && Day >= 21) || (Month == 7 && Day <= 22)
                zodiac = "Yengeç";
            elseif (Month == 7 && Day >= 23) || (Month == 8 && Day <= 22)
                zodiac = "Aslan";
            elseif (Month == 8 && Day >= 23) || (Month == 9 && Day <= 22)
                zodiac = "Başak";
            elseif (Month == 9 && Day >= 23) || (Month == 10 && Day <= 22)
                zodiac = "Terazi";
            elseif (Month == 10 && Day >= 23) || (Month == 11 && Day <= 21)
                zodiac = "Akrep";
            elseif (Month == 11 && Day >= 22) || (Month == 12 && Day <= 21)
                zodiac = "Yay";
            else
                zodiac = "Oğlak";
            end
        end

        function [forYears,forMonths,forDays] = AgeCalculate(~,firstDate,secondDate)
            age = between(firstDate,secondDate, {'years','months','days'});
            forYears = calyears(age);
            forMonths = calmonths(age - calyears(forYears));
            forDays = caldays(age - calyears(forYears) - calmonths(forMonths));
        end
    
        function DataUpdate2(app,~)
            data = fetch(app.SqlConnections{1},app.query1);
            app.UITable2.ColumnName = data.Properties.VariableNames;
            app.UITable2.Data = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GiriButton
        function GiriButtonPushed(app, event)
            app.GiriButton.Visible = "off";
            app.YkleniyorGauge.Visible = "on";
            app.YkleniyorGaugeLabel.Visible = "on";

            i = 0;
            while i <= 100
                pause(0.2)
                i = i + randi([1 50]);
                app.YkleniyorGauge.Value = app.YkleniyorGauge.Value + i;
                if i >= 100
                    break
                end
            end
            
            try
                app.SqlConnections{end+1} = database(app.nameDb,'','');
                app.BalantLamp.Color = 'green';
                app.BalanButton.Visible = "off";
            catch ME
                uialert(app.UIFigure, sprintf('Bağlantı sağlanamadı: %s', ME.message), 'Hata');
                app.BalantLamp.Color = 'red';
            end
            
            app.YkleniyorGauge.Visible = "off";
            app.YkleniyorGaugeLabel.Visible = "off";
            app.YasHesaplamaProjesiPanel.Visible = "off";
        end

        % Value changed function: BirTarihSeinizDatePicker
        function BirTarihSeinizDatePickerValueChanged(app, event)
            value = app.BirTarihSeinizDatePicker.Value;
            if value ~= datetime("today")
                app.GnmzCheckBox.Value = 0;
            else
                app.GnmzCheckBox.Value = 1;
            end
        end

        % Value changed function: GnmzCheckBox
        function GnmzCheckBoxValueChanged(app, event)
            value = app.GnmzCheckBox.Value;
            if isempty(value)
            else
                app.BirTarihSeinizDatePicker.Value = datetime("today");
            end
        end

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
            birthDate = app.DoumTarihinizDatePicker.Value;
            selectedDate = app.BirTarihSeinizDatePicker.Value;

            if isnat(birthDate) || isnat(selectedDate)
                msgbox("Lütfen Geçerli Bir Değer Giriniz","Uyarı!")
                return
            elseif birthDate >= selectedDate
                msgbox("Girilen doğum tarihi, seçilen tarihten ileri olmamalıdır.","Uyarı!")
                return
            end
    
            [year, month, day] = app.AgeCalculate(birthDate,selectedDate);
            zodiac = app.FindZodiac(str2double(datestr(birthDate,'mm')),str2double(datestr(birthDate,'dd')));
            msgbox([sprintf("Burç: %s",zodiac);sprintf("Yıl: %d",year);sprintf("Ay: %d",month);sprintf("Gün: %d",day)])
        end

        % Button pushed function: YaHesaplaButton
        function YaHesaplaButtonPushed(app, event)
            app.MenPanel.Visible = "off";
            app.ActivePanel = app.TabGroup;
            app.TabGroup.Visible = "on";
        end

        % Button pushed function: KaytlarGetirButton
        function KaytlarGetirButtonPushed(app, event)
            try
                data = fetch(app.SqlConnections{1},app.query1);
                app.UITable.ColumnName = data.Properties.VariableNames;
                app.UITable.Data = data;
                app.UITable.ColumnSortable = true;
            catch ME
                uialert(app.UIFigure, sprintf('Bağlantı sağlanamadı: %s', ME.message), 'Hata');
            end
        end

        % Button pushed function: KaytlarGsterButton
        function KaytlarGsterButtonPushed(app, event)
            app.MenPanel.Visible = "off";
            app.ActivePanel = app.KaytlarGstermePanel;
            app.KaytlarGstermePanel.Visible = "on";
        end

        % Button pushed function: KapatButton
        function KapatButtonPushed(app, event)
            if ~isempty(app.SqlConnections)
                for i = 1: length(app.SqlConnections)
                    close(app.SqlConnections{i})
                end
            end
            delete(app.UIFigure)
        end

        % Button pushed function: MenyeDnButton
        function MenyeDnButtonPushed(app, event)
            app.ActivePanel.Visible = "off";
            app.MenPanel.Visible = "on";
        end

        % Button pushed function: KaydetButton
        function KaydetButtonPushed(app, event)
            if isempty(app.AdEditField_2.Value) || isempty(app.SoyadEditField_2.Value) || isnat(app.DoumTarihiDatePicker_2.Value)
                msgbox("Lütfen boş alanları doldurunuz.","Uyarı!")
                return
            end

            name = app.AdEditField_2.Value;
            lastname = app.SoyadEditField_2.Value;
            dogumtarihi = app.DoumTarihiDatePicker_2.Value;

            query = sprintf("INSERT INTO Matlab.dbo.Registrations " + ...
                "(Name, Lastname, BirthDate) VALUES ('%s', '%s', '%s')", ...
                name, lastname, datestr(dogumtarihi, 'yyyy-mm-dd'));

            try
                exec(app.SqlConnections{1}, query);
                uialert(app.UIFigure, "Kayıt başarılı bir şekilde eklendi.", 'Başarılı');
                app.AdEditField_2.Value = "";
                app.SoyadEditField_2.Value = "";
                app.DoumTarihiDatePicker_2.Value = NaT('Format', 'dd-MM-yyyy');
            catch ME
                uialert(app.UIFigure, sprintf('Kayıt eklenemedi: %s', ME.message), 'Hata');
            end
        end

        % Button pushed function: KaytEkleButton
        function KaytEkleButtonPushed(app, event)
            app.MenPanel.Visible = "off";
            app.ActivePanel = app.KaytEklemePanel;
            app.KaytEklemePanel.Visible = 'on';
        end

        % Button pushed function: SilButton
        function SilButtonPushed(app, event)
            id = app.IDEditField_2.Value;

            if id == 0
                msgbox("Lütfen ID giriniz.")
                return
            end

            query = sprintf('delete from Matlab.dbo.Registrations where ID = %d', id);
            
            try
                ishave_id = sprintf("select ID from Matlab.dbo.Registrations where ID = %d", id);
                ishave = fetch(app.SqlConnections{1},string(ishave_id));
                if isempty(ishave)
                    msgbox("Girilen ID'ye sahip kayıt bulunmamaktadır.")
                    return
                end
                exec(app.SqlConnections{1}, query);
                uialert(app.UIFigure, sprintf('ID: %d başarıyla silindi.', id), 'Başarılı');
            catch ME
                uialert(app.UIFigure, sprintf('Kayıt silinemedi: %s', ME.message), 'Hata');
            end
        end

        % Button pushed function: GncelleButton
        function GncelleButtonPushed(app, event)
            if app.IDEditField.Value == 0
                msgbox("Lütfen bir 'ID' giriniz.","Uyarı!")
                return
            end

            id = app.IDEditField.Value;
            name = app.AdEditField.Value;
            lastname = app.SoyadEditField.Value;
            birthdate = app.DoumTarihiDatePicker.Value;
            
            birthMonth = month(birthdate);
            birthDay = day(birthdate);
            
            zodiac = app.FindZodiac(birthMonth,birthDay);
            
            if app.AdCheckBox.Value == 0 && app.SoyadCheckBox.Value == 0 && app.DoumTarihiCheckBox.Value == 0
                    msgbox("Lütfen en az bir kutucuğu işaretleyiniz.")
                    return
            else
                if app.AdCheckBox.Value == 1
                    if ~isempty(app.AdEditField.Value)
                        if app.SoyadCheckBox.Value == 1
                            if ~isempty(app.SoyadEditField.Value)
                                if app.DoumTarihiCheckBox.Value == 1
                                    if ~isnat(app.DoumTarihiDatePicker.Value)
                                        queryUpd = sprintf("update Matlab.dbo.Registrations set Name = '%s', " + ...
                                            "Lastname = '%s', Birthdate = '%s', Zodiac_sign = '%s'" + ...
                                            " where ID = %d", ...
                                            name, lastname, datestr(birthdate, 'yyyy-mm-dd'), zodiac, id);
                                    else
                                        msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                                        return
                                    end
                                else
                                    queryUpd = sprintf("update Matlab.dbo.Registrations set Name = '%s', " + ...
                                        "Lastname = '%s' where ID = %d", ...
                                        name, lastname, id);
                                end
                            else
                                msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                                return
                            end
                        elseif app.DoumTarihiCheckBox.Value == 1
                            if ~isnat(app.DoumTarihiDatePicker.Value)
                                    queryUpd = sprintf("update Matlab.dbo.Registrations set Name = '%s', " + ...
                                        "Birthdate = '%s', Zodiac_sign = '%s'" + ...
                                        " where ID = %d", ...
                                        name, datestr(birthdate, 'yyyy-mm-dd'), zodiac, id);
                            else
                                msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                                return
                            end
                        else
                            queryUpd = sprintf("update Matlab.dbo.Registrations set Name = '%s' where ID = %d", ...
                                name, id);
                        end
                    else
                        msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                        return
                    end
                elseif app.SoyadCheckBox.Value == 1
                    if ~isempty(app.SoyadEditField.Value)
                        if app.DoumTarihiCheckBox.Value == 1
                            if ~isnat(app.DoumTarihiDatePicker.Value)
                                queryUpd = sprintf("update Matlab.dbo.Registrations set Lastname = '%s', " + ...
                                    "Birthdate = '%s', Zodiac_sign = '%s'" + ...
                                    " where ID = %d", ...
                                    lastname, datestr(birthdate, 'yyyy-mm-dd'), zodiac, id);
                            else
                                msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                                return
                            end
                        else
                            queryUpd = sprintf("update Matlab.dbo.Registrations set Lastname = '%s' where ID = %d", ...
                                lastname, id);
                        end
                    else
                        msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                        return
                    end
                elseif app.DoumTarihiCheckBox.Value == 1
                    if ~isnat(app.DoumTarihiDatePicker.Value)
                        queryUpd = sprintf("update Matlab.dbo.Registrations set Birthdate = '%s', Zodiac_sign = '%s'" + ...
                            " where ID = %d", ...
                            datestr(birthdate, 'yyyy-mm-dd'), zodiac, id);
                    else
                        msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                        return
                    end
                else
                    msgbox("Lütfen seçtiğiniz ve boş bıraktığınız alanları doldurunuz.","Uyarı!")
                    return
                end
            end

            try
                ishave_id = sprintf("select ID from Matlab.dbo.Registrations where ID = %d", id);
                ishave = fetch(app.SqlConnections{1},string(ishave_id));
                if isempty(ishave)
                    msgbox("Girilen ID'ye sahip kayıt bulunmamaktadır.")
                    return
                end
                exec(app.SqlConnections{1}, queryUpd);
                uialert(app.UIFigure, sprintf('ID: %d başarıyla güncellendi.', id), 'Başarılı');
                app.IDEditField.Value = 0;
                app.AdEditField.Value = "";
                app.SoyadEditField.Value = "";
                app.DoumTarihiDatePicker.Value = NaT('Format', 'dd-MM-yyyy');
            catch ME
                uialert(app.UIFigure, sprintf('Kayıt güncellenemedi: %s', ME.message), 'Hata');
            end
        end

        % Button pushed function: BalanButton
        function BalanButtonPushed(app, event)
            try
                app.BalantLamp.Color = 'yellow';
                pause(0.2)
                app.SqlConnections{end+1} = database('MatlabProje','','');
                app.BalanButton.Visible = "off";
                app.BalantLamp.Color = 'green';
            catch ME
                app.BalantLamp.Color = 'red';
                uialert(app.UIFigure, sprintf('Bağlantı sağlanamadı: %s', ME.message), 'Hata');
            end
        end

        % Button pushed function: KaytGncelleButton
        function KaytGncelleButtonPushed(app, event)
            app.MenPanel.Visible = "off";
            app.ActivePanel = app.KaytGncellemePanel;
            app.KaytGncellemePanel.Visible = "on";
        end

        % Button pushed function: KaytSilButton
        function KaytSilButtonPushed(app, event)
            app.MenPanel.Visible = "off";
            app.ActivePanel = app.KaytsilmePanel;
            app.KaytsilmePanel.Visible = "on";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'Kayıtlı')
                try
                    data = fetch(app.SqlConnections{1},app.query1);
                    if ~isempty(data)
                        app.DataUpdate2(app)
                        app.IDDropDown.Items = string(data.ID);
                    else
                        msgbox("Veritabanında kayıtlı veri bulunmamaktadır.","Uyarı!")
                    end
                catch ME
                    msgbox(ME.message)
                end
            end
        end

        % Button pushed function: HesaplaButton_2
        function HesaplaButton_2Pushed(app, event)
            id = str2double(app.IDDropDown.Value);
            
            if isempty(id)
                msgbox("Kayıtlı veri olmadığı için hesaplanamaz.","Uyarı!")
                return
            end

            queryAge = sprintf(app.query1 + " where ID = %d", id);
            queryDate = sprintf("select month(Birthdate) as m, day(Birthdate) as d from Registrations where ID = %d", id);

            try
                dataAge = fetch(app.SqlConnections{1},queryAge);
                [year, month, day] = app.AgeCalculate(datetime(dataAge.Birthdate),datetime("today"));
                dataAge = fetch(app.SqlConnections{1},queryDate);
                zodiac = app.FindZodiac(dataAge.m,dataAge.d);

                if app.VeritabannaKaydetCheckBox.Value == 1
                    queryR = sprintf("update Registrations set Age = %d, Zodiac_sign = '%s' where ID = %d", year, zodiac, id);
                    exec(app.SqlConnections{1},queryR);
                    app.DataUpdate2(app)
                end

                msgbox([sprintf("%d ID'ye sahip kayıt: %d",id);sprintf("%d yıl, %d ay, %d gün önce doğdu.",year,month,day);sprintf("Burcu: %s",zodiac)],"Bilgilendirme!")
            catch ME
                uialert(app,ME.message,"Hata!")
            end
        end

        % Value changed function: BurSwitch, DoumTarihiSwitch, IDSwitch, 
        % ...and 3 other components
        function SwitchsValueChanged(app, event)
            swinfo = event.Source;

            tagToFieldMap = struct( ...
                "ID", app.IDSearchEditField, ...
                "Name", app.NameSearchEditField, ...
                "Lastname", app.LastnameSearchEditField, ...
                "Age", app.AgeSearchEditField, ...
                "Birthdate", app.DateSearchDatePicker, ...
                "Zodiac", app.ZodiacSearchEditField);
            
            if strcmpi(swinfo.Value, 'on')
                if isempty(app.ActiveSwitch) || ~any(ismember(app.ActiveSwitch, string(swinfo.Tag)))
                    app.ActiveSwitch = [app.ActiveSwitch, string(swinfo.Tag)];
                end

                tagToFieldMap.(swinfo.Tag).Visible = "on";

                if strcmp(swinfo.Tag,"ID")
                    app.IDButtonGroup.Visible = "on";
                    if app.IDButtonGroup.SelectedObject.Text == "~"
                        app.IDSearchEditField_2.Visible = "on";
                    end
                elseif strcmp(swinfo.Tag,"Age")
                    app.AgeButtonGroup.Visible = "on";
                    if app.AgeButtonGroup.SelectedObject.Text == "~"
                        app.AgeSearchEditField_2.Visible = "on";
                    end
                elseif strcmp(swinfo.Tag,"Birthdate")
                    app.DateButtonGroup.Visible = "on";
                    if app.DateButtonGroup.SelectedObject.Text == "~"
                        app.DateSearchDatePicker_2.Visible = "on";
                    end
                end
            else
                app.ActiveSwitch(strcmp(app.ActiveSwitch, string(swinfo.Tag))) = [];
                tagToFieldMap.(swinfo.Tag).Visible = "off";

                if strcmp(swinfo.Tag,"ID")
                    tagToFieldMap.(swinfo.Tag).Value = 0;
                    app.IDButtonGroup.Visible = "off";
                    app.IDButtonGroup.Buttons(4).Value = 1;
                    app.IDSearchEditField_2.Visible = "off";
                    app.IDSearchEditField_2.Value = 0;
                elseif strcmp(swinfo.Tag,"Age")
                    app.AgeButtonGroup.Visible = "off";
                    app.AgeButtonGroup.Buttons(4).Value = 1;
                    app.AgeSearchEditField_2.Visible = "off";
                    app.AgeSearchEditField_2.Value = 0;
                elseif strcmp(swinfo.Tag,"Birthdate")
                    app.DateButtonGroup.Visible = "off";
                    app.DateButtonGroup.Buttons(4).Value = 1;
                    app.DateSearchDatePicker_2.Visible = "off";
                    app.DateSearchDatePicker_2.Value = NaT;
                end
            end

            if isempty(app.ActiveSwitch)
                app.AraButton.Visible = "off";
            else
                app.AraButton.Visible = "on";
            end
        end

        % Button pushed function: AraButton
        function AraButtonPushed(app, event)
            if isempty(app.ActiveSwitch)
                msgbox("Lütfen en az bir seçeneği aktif ediniz.","Uyarı!")
                return
            end

            sqlQuery = [];
            params = [];

            for i = 1: length(app.ActiveSwitch)
                sw = app.ActiveSwitch(i);
                if sw == "ID"
                    if app.IDSearchEditField.Value ~= 0
                        if app.IDButtonGroup.SelectedObject.Text == "~"
                            if app.IDSearchEditField_2.Value ~= 0
                                sqlQuery = [sqlQuery, "ID between %d and %d"];
                                params = [params, {min(app.IDSearchEditField.Value,app.IDSearchEditField_2.Value),...
                                    max(app.IDSearchEditField.Value,app.IDSearchEditField_2.Value)}];
                            else
                                msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                                return
                            end
                        elseif app.IDButtonGroup.SelectedObject.Text == "<"
                            sqlQuery = [sqlQuery, 'ID < %d'];
                            params = [params, {app.IDSearchEditField.Value}];
                        elseif app.IDButtonGroup.SelectedObject.Text == ">"
                            sqlQuery = [sqlQuery, 'ID > %d'];
                            params = [params, {app.IDSearchEditField.Value}];
                        elseif app.IDButtonGroup.SelectedObject.Text == "<="
                            sqlQuery = [sqlQuery, 'ID <= %d'];
                            params = [params, {app.IDSearchEditField.Value}];
                        elseif app.IDButtonGroup.SelectedObject.Text == ">="
                            sqlQuery = [sqlQuery, 'ID >= %d'];
                            params = [params, {app.IDSearchEditField.Value}];
                        else
                            sqlQuery = [sqlQuery, 'ID like %d'];
                            params = [params, {app.IDSearchEditField.Value}];
                        end
                    else
                        msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                        return
                    end
                elseif sw == "Name"
                    if ~isempty(app.NameSearchEditField.Value)
                        sqlQuery = [sqlQuery, "Name like '%s'"];
                        params = [params, {['%' app.NameSearchEditField.Value '%']}];
                    else
                        msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                        return
                    end
                elseif sw == "Lastname"
                    if ~isempty(app.LastnameSearchEditField.Value)
                        sqlQuery = [sqlQuery, "Lastname like '%s'"];
                        params = [params, {['%' app.LastnameSearchEditField.Value '%']}];
                    else
                        msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                        return
                    end
                elseif sw == "Age"
                    if app.AgeSearchEditField.Value ~= 0
                        if app.AgeButtonGroup.SelectedObject.Text == "~"
                            if app.AgeSearchEditField_2.Value ~= 0
                                sqlQuery = [sqlQuery, "Age between %d and %d"];
                                params = [params, {min(app.AgeSearchEditField.Value,app.AgeSearchEditField_2.Value),...
                                    max(app.AgeSearchEditField.Value,app.AgeSearchEditField_2.Value)}];
                            else
                                msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                                return
                            end
                        elseif app.AgeButtonGroup.SelectedObject.Text == "<"
                            sqlQuery = [sqlQuery, "Age < %d"];
                            params = [params, {app.AgeSearchEditField.Value}];
                        elseif app.AgeButtonGroup.SelectedObject.Text == ">"
                            sqlQuery = [sqlQuery, "Age > %d"];
                            params = [params, {app.AgeSearchEditField.Value}];
                        elseif app.AgeButtonGroup.SelectedObject.Text == "<="
                            sqlQuery = [sqlQuery, "Age <= %d"];
                            params = [params, {app.AgeSearchEditField.Value}];
                        elseif app.AgeButtonGroup.SelectedObject.Text == ">="
                            sqlQuery = [sqlQuery, "Age >= %d"];
                            params = [params, {app.AgeSearchEditField.Value}];
                        else
                            sqlQuery = [sqlQuery, "Age like %d"];
                            params = [params, {app.AgeSearchEditField.Value}];
                        end
                    else
                        msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                        return
                    end
                elseif sw == "Birthdate"
                    if ~isnat(app.DateSearchDatePicker.Value)
                        if app.DateButtonGroup.SelectedObject.Text == "~"
                            if ~isnat(app.DateSearchDatePicker_2.Value)
                                sqlQuery = [sqlQuery, "Birthdate between '%s' and '%s'"];
                                params = [params, {min(app.DateSearchDatePicker.Value,app.DateSearchDatePicker_2.Value),...
                                    max(app.DateSearchDatePicker.Value,app.DateSearchDatePicker_2.Value)}];
                            else
                                msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                                return
                            end
                        elseif app.DateButtonGroup.SelectedObject.Text == "<"
                            sqlQuery = [sqlQuery, "Birthdate < '%s'"];
                            params = [params, {app.DateSearchDatePicker.Value}];
                        elseif app.DateButtonGroup.SelectedObject.Text == ">"
                            sqlQuery = [sqlQuery, "Birthdate > '%s'"];
                            params = [params, {app.DateSearchDatePicker.Value}];
                        elseif app.DateButtonGroup.SelectedObject.Text == "<="
                            sqlQuery = [sqlQuery, "Birthdate <= '%s'"];
                            params = [params, {app.DateSearchDatePicker.Value}];
                        elseif app.DateButtonGroup.SelectedObject.Text == ">="
                            sqlQuery = [sqlQuery, "Birthdate >= '%s'"];
                            params = [params, {app.DateSearchDatePicker.Value}];
                        else
                            sqlQuery = [sqlQuery, "Birthdate = '%s'"];
                            params = [params, {app.DateSearchDatePicker.Value}];
                        end
                    else
                        msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                        return
                    end
                    
                elseif sw == "Zodiac"
                    if ~isempty(app.ZodiacSearchEditField.Value)
                        sqlQuery = [sqlQuery, "Zodiac_sign like '%s'"];
                        params = [params, {['%' app.ZodiacSearchEditField.Value '%']}];
                    else
                        msgbox("Lütfen aktif seçenekleri boş bırakmayınız.","Uyarı!")
                        return
                    end
                end
            end

            if length(app.ActiveSwitch) > 1
                conQuery = strjoin(sqlQuery, ' and ');
            else
                conQuery = sqlQuery;
            end
            
            try
                query = sprintf("select * from Registrations where " + conQuery,params{:});
                data = fetch(app.SqlConnections{1},query);
                app.UITable.ColumnName = data.Properties.VariableNames;
                app.UITable.Data = data;
            catch ME
                uialert(app.UIFigure, sprintf('Kayıt aranamadı: %s', ME.message), 'Hata');
            end
        end

        % Selection changed function: AgeButtonGroup, DateButtonGroup, 
        % ...and 1 other component
        function ButtonGroupsSelectionChanged(app, event)
            selectedBG = event.Source;

            if strcmp(selectedBG.Tag,"IDbg")
                if selectedBG.SelectedObject.Text == '~'
                    app.IDSearchEditField_2.Visible = "on";
                else
                    app.IDSearchEditField_2.Visible = "off";
                    app.IDSearchEditField_2.Value = 0;
                end
            elseif strcmp(selectedBG.Tag,"Agebg")
                if selectedBG.SelectedObject.Text == '~'
                    app.AgeSearchEditField_2.Visible = "on";
                else
                    app.AgeSearchEditField_2.Visible = "off";
                    app.AgeSearchEditField_2.Value = 0;
                end
            elseif strcmp(selectedBG.Tag,"Datebg")
                if selectedBG.SelectedObject.Text == '~'
                    app.DateSearchDatePicker_2.Visible = "on";
                else
                    app.DateSearchDatePicker_2.Visible = "off";
                    app.DateSearchDatePicker_2.Value = NaT;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BalantLampLabel = uilabel(app.UIFigure);
            app.BalantLampLabel.Text = 'Bağlantı';
            app.KaytsilmePanel = uipanel(app.UIFigure);
            app.KaytsilmePanel.Title = 'Kayıt silme';
            app.KaytsilmePanel.Visible = 'off';
            app.SilButton = uibutton(app.KaytsilmePanel, 'push');
            app.SilButton.ButtonPushedFcn = createCallbackFcn(app, @SilButtonPushed, true);
            app.SilButton.Text = 'Sil';
            app.IDEditField_2Label = uilabel(app.KaytsilmePanel);
            app.IDEditField_2Label.Text = 'ID';
            app.IDEditField_2 = uieditfield(app.KaytsilmePanel, 'numeric');
            app.GirilenIDyegrekaytsilinecektirLabel = uilabel(app.KaytsilmePanel);
            app.GirilenIDyegrekaytsilinecektirLabel.Text = '*Girilen ID''ye göre kayıt silinecektir.';
            app.KaytGncellemePanel = uipanel(app.UIFigure);
            app.KaytGncellemePanel.Title = 'Kayıt Güncelleme';
            app.KaytGncellemePanel.Visible = 'off';
            app.AdEditFieldLabel = uilabel(app.KaytGncellemePanel);
            app.AdEditFieldLabel.Text = 'Ad';
            app.AdEditField = uieditfield(app.KaytGncellemePanel, 'text');
            app.SoyadEditFieldLabel = uilabel(app.KaytGncellemePanel);
            app.SoyadEditFieldLabel.Text = 'Soyad';
            app.SoyadEditField = uieditfield(app.KaytGncellemePanel, 'text');
            app.DoumTarihiDatePickerLabel = uilabel(app.KaytGncellemePanel);
            app.DoumTarihiDatePickerLabel.Text = 'Doğum Tarihi';
            app.DoumTarihiDatePicker = uidatepicker(app.KaytGncellemePanel);
            app.GncelleButton = uibutton(app.KaytGncellemePanel, 'push');
            app.GncelleButton.ButtonPushedFcn = createCallbackFcn(app, @GncelleButtonPushed, true);
            app.GncelleButton.Text = 'Güncelle';
            app.IDEditFieldLabel = uilabel(app.KaytGncellemePanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.KaytGncellemePanel, 'numeric');
            app.GirilenIDyegregncellemeyaplacaktrLabel = uilabel(app.KaytGncellemePanel);
            app.GirilenIDyegregncellemeyaplacaktrLabel.Text = '*Girilen ID''ye göre güncelleme yapılacaktır.';
            app.AdCheckBox = uicheckbox(app.KaytGncellemePanel);
            app.AdCheckBox.Text = 'Ad';
            app.AdCheckBox.Value = true;
            app.SoyadCheckBox = uicheckbox(app.KaytGncellemePanel);
            app.SoyadCheckBox.Text = 'Soyad';
            app.SoyadCheckBox.Value = true;
            app.DoumTarihiCheckBox = uicheckbox(app.KaytGncellemePanel);
            app.DoumTarihiCheckBox.Text = 'Doğum Tarihi';
            app.DoumTarihiCheckBox.Value = true;
            app.SeilenyenitarihegreyaveburotomatikolarakkaydedilecektirLabel = uilabel(app.KaytGncellemePanel);
            app.SeilenyenitarihegreyaveburotomatikolarakkaydedilecektirLabel.Text = '*Seçilen yeni tarihe göre yaş ve burç otomatik olarak kaydedilecektir.';
            app.KaytEklemePanel = uipanel(app.UIFigure);
            app.KaytEklemePanel.Title = 'Kayıt Ekleme';
            app.KaytEklemePanel.Visible = 'off';
            app.AdEditField_2Label = uilabel(app.KaytEklemePanel);
            app.AdEditField_2Label.Text = 'Ad';
            app.AdEditField_2 = uieditfield(app.KaytEklemePanel, 'text');
            app.SoyadEditField_2Label = uilabel(app.KaytEklemePanel);
            app.SoyadEditField_2Label.Text = 'Soyad';
            app.SoyadEditField_2 = uieditfield(app.KaytEklemePanel, 'text');
            app.DoumTarihiDatePicker_2Label = uilabel(app.KaytEklemePanel);
            app.DoumTarihiDatePicker_2Label.Text = 'Doğum Tarihi';
            app.DoumTarihiDatePicker_2 = uidatepicker(app.KaytEklemePanel);
            app.KaydetButton = uibutton(app.KaytEklemePanel, 'push');
            app.KaydetButton.ButtonPushedFcn = createCallbackFcn(app, @KaydetButtonPushed, true);
            app.KaydetButton.Text = 'Kaydet';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.KaytszTab = uitab(app.TabGroup);
            app.KaytszTab.Title = 'Kayıtsız';
            app.DoumTarihinizDatePickerLabel = uilabel(app.KaytszTab);
            app.DoumTarihinizDatePickerLabel.Text = 'Doğum Tarihiniz:';
            app.DoumTarihinizDatePicker = uidatepicker(app.KaytszTab);
            app.DoumTarihinizDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.BirTarihSeinizDatePickerLabel = uilabel(app.KaytszTab);
            app.BirTarihSeinizDatePickerLabel.Text = 'Bir Tarih Seçiniz:';
            app.BirTarihSeinizDatePicker = uidatepicker(app.KaytszTab);
            app.BirTarihSeinizDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.BirTarihSeinizDatePicker.ValueChangedFcn = createCallbackFcn(app, @BirTarihSeinizDatePickerValueChanged, true);
            app.GnmzCheckBox = uicheckbox(app.KaytszTab);
            app.GnmzCheckBox.ValueChangedFcn = createCallbackFcn(app, @GnmzCheckBoxValueChanged, true);
            app.GnmzCheckBox.Text = 'Günümüz';
            app.HesaplaButton = uibutton(app.KaytszTab, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.KaytlTab = uitab(app.TabGroup);
            app.KaytlTab.Title = 'Kayıtlı';
            app.IDDropDownLabel = uilabel(app.KaytlTab);
            app.IDDropDownLabel.Text = 'ID';
            app.IDDropDown = uidropdown(app.KaytlTab);
            app.IDDropDown.Items = {'''Kayıtlı Veri Yok'''};
            app.IDDropDown.Value = '''Kayıtlı Veri Yok''';
            app.VeritabannaKaydetCheckBox = uicheckbox(app.KaytlTab);
            app.VeritabannaKaydetCheckBox.Text = 'Veritabanına Kaydet';
            app.UITable2 = uitable(app.KaytlTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.SeilenIDyegreyaveburhesaplamasyaplacaktrLabel = uilabel(app.KaytlTab);
            app.SeilenIDyegreyaveburhesaplamasyaplacaktrLabel.Text = '*Seçilen ID''ye göre yaş ve burç hesaplaması yapılacaktır.';
            app.HesaplaButton_2 = uibutton(app.KaytlTab, 'push');
            app.HesaplaButton_2.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButton_2Pushed, true);
            app.HesaplaButton_2.Text = 'Hesapla';
            app.KaytlarGstermePanel = uipanel(app.UIFigure);
            app.KaytlarGstermePanel.Title = 'Kayıtları Gösterme';
            app.KaytlarGstermePanel.Visible = 'off';
            app.UITable = uitable(app.KaytlarGstermePanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.KaytlarGetirButton = uibutton(app.KaytlarGstermePanel, 'push');
            app.KaytlarGetirButton.ButtonPushedFcn = createCallbackFcn(app, @KaytlarGetirButtonPushed, true);
            app.KaytlarGetirButton.Text = 'Kayıtları Getir';
            app.IDSearchEditFieldLabel = uilabel(app.KaytlarGstermePanel);
            app.IDSearchEditFieldLabel.Visible = 'off';
            app.IDSearchEditFieldLabel.Text = 'IDSearch';
            app.IDSearchEditField = uieditfield(app.KaytlarGstermePanel, 'numeric');
            app.IDSearchEditField.Limits = [0 Inf];
            app.IDSearchEditField.Visible = 'off';
            app.NameSearchEditFieldLabel = uilabel(app.KaytlarGstermePanel);
            app.NameSearchEditFieldLabel.Visible = 'off';
            app.NameSearchEditFieldLabel.Text = 'NameSearch';
            app.NameSearchEditField = uieditfield(app.KaytlarGstermePanel, 'text');
            app.NameSearchEditField.Visible = 'off';
            app.AgeSearchEditField = uieditfield(app.KaytlarGstermePanel, 'numeric');
            app.AgeSearchEditField.Limits = [0 Inf];
            app.AgeSearchEditField.Visible = 'off';
            app.IDSwitchLabel = uilabel(app.KaytlarGstermePanel);
            app.IDSwitchLabel.Text = 'ID';
            app.simSwitchLabel = uilabel(app.KaytlarGstermePanel);
            app.simSwitchLabel.Text = 'İsim';
            app.SoyisimSwitchLabel = uilabel(app.KaytlarGstermePanel);
            app.SoyisimSwitchLabel.Text = 'Soyisim';
            app.YaSwitchLabel = uilabel(app.KaytlarGstermePanel);
            app.YaSwitchLabel.Text = 'Yaş';
            app.DoumTarihiSwitchLabel = uilabel(app.KaytlarGstermePanel);
            app.DoumTarihiSwitchLabel.Text = 'Doğum Tarihi';
            app.LastnameSearchEditFieldLabel = uilabel(app.KaytlarGstermePanel);
            app.LastnameSearchEditFieldLabel.Visible = 'off';
            app.LastnameSearchEditFieldLabel.Text = 'LastnameSearch';
            app.LastnameSearchEditField = uieditfield(app.KaytlarGstermePanel, 'text');
            app.LastnameSearchEditField.Visible = 'off';
            app.BurSwitchLabel = uilabel(app.KaytlarGstermePanel);
            app.BurSwitchLabel.Text = 'Burç';
            app.AgeSearchEditFieldLabel = uilabel(app.KaytlarGstermePanel);
            app.AgeSearchEditFieldLabel.Visible = 'off';
            app.AgeSearchEditFieldLabel.Text = 'AgeSearch';
            app.ZodiacSearchEditFieldLabel = uilabel(app.KaytlarGstermePanel);
            app.ZodiacSearchEditFieldLabel.Visible = 'off';
            app.ZodiacSearchEditFieldLabel.Text = 'ZodiacSearch';
            app.ZodiacSearchEditField = uieditfield(app.KaytlarGstermePanel, 'text');
            app.ZodiacSearchEditField.Visible = 'off';
            app.DateSearchDatePickerLabel = uilabel(app.KaytlarGstermePanel);
            app.DateSearchDatePickerLabel.Visible = 'off';
            app.DateSearchDatePickerLabel.Text = 'DateSearch';
            app.DateSearchDatePicker = uidatepicker(app.KaytlarGstermePanel);
            app.DateSearchDatePicker.Visible = 'off';
            app.BurSwitch = uiswitch(app.KaytlarGstermePanel, 'slider');
            app.BurSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchsValueChanged, true);
            app.BurSwitch.Tag = 'Zodiac';
            app.DoumTarihiSwitch = uiswitch(app.KaytlarGstermePanel, 'slider');
            app.DoumTarihiSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchsValueChanged, true);
            app.DoumTarihiSwitch.Tag = 'Birthdate';
            app.YaSwitch = uiswitch(app.KaytlarGstermePanel, 'slider');
            app.YaSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchsValueChanged, true);
            app.YaSwitch.Tag = 'Age';
            app.SoyisimSwitch = uiswitch(app.KaytlarGstermePanel, 'slider');
            app.SoyisimSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchsValueChanged, true);
            app.SoyisimSwitch.Tag = 'Lastname';
            app.simSwitch = uiswitch(app.KaytlarGstermePanel, 'slider');
            app.simSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchsValueChanged, true);
            app.simSwitch.Tag = 'Name';
            app.IDSwitch = uiswitch(app.KaytlarGstermePanel, 'slider');
            app.IDSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchsValueChanged, true);
            app.IDSwitch.Tag = 'ID';
            app.AraButton = uibutton(app.KaytlarGstermePanel, 'push');
            app.AraButton.ButtonPushedFcn = createCallbackFcn(app, @AraButtonPushed, true);
            app.AraButton.Visible = 'off';
            app.AraButton.Text = 'Ara';
            app.IDSearchEditField_2Label = uilabel(app.KaytlarGstermePanel);
            app.IDSearchEditField_2Label.Visible = 'off';
            app.IDSearchEditField_2Label.Text = 'IDSearch';
            app.IDSearchEditField_2 = uieditfield(app.KaytlarGstermePanel, 'numeric');
            app.IDSearchEditField_2.Limits = [0 Inf];
            app.IDSearchEditField_2.Visible = 'off';
            app.AgeSearchEditField_2 = uieditfield(app.KaytlarGstermePanel, 'numeric');
            app.AgeSearchEditField_2.Limits = [0 Inf];
            app.AgeSearchEditField_2.Visible = 'off';
            app.AgeSearchEditField_2Label = uilabel(app.KaytlarGstermePanel);
            app.AgeSearchEditField_2Label.Visible = 'off';
            app.AgeSearchEditField_2Label.Text = 'AgeSearch';
            app.DateSearchDatePicker_2Label = uilabel(app.KaytlarGstermePanel);
            app.DateSearchDatePicker_2Label.Visible = 'off';
            app.DateSearchDatePicker_2Label.Text = 'DateSearch';
            app.DateSearchDatePicker_2 = uidatepicker(app.KaytlarGstermePanel);
            app.DateSearchDatePicker_2.Visible = 'off';
            app.IDButtonGroup = uibuttongroup(app.KaytlarGstermePanel);
            app.IDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupsSelectionChanged, true);
            app.IDButtonGroup.Visible = 'off';
            app.IDButtonGroup.Tag = 'IDbg';
            app.Button_6 = uiradiobutton(app.IDButtonGroup);
            app.Button_6.Text = '~';
            app.Button_5 = uiradiobutton(app.IDButtonGroup);
            app.Button_5.Text = '>=';
            app.Button_4 = uiradiobutton(app.IDButtonGroup);
            app.Button_4.Text = '<=';
            app.Button_3 = uiradiobutton(app.IDButtonGroup);
            app.Button_3.Text = '=';
            app.Button_3.Value = true;
            app.Button_2 = uiradiobutton(app.IDButtonGroup);
            app.Button_2.Text = '>';
            app.Button = uiradiobutton(app.IDButtonGroup);
            app.Button.Text = '<';
            app.AgeButtonGroup = uibuttongroup(app.KaytlarGstermePanel);
            app.AgeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupsSelectionChanged, true);
            app.AgeButtonGroup.Visible = 'off';
            app.AgeButtonGroup.Tag = 'Agebg';
            app.Button_13 = uiradiobutton(app.AgeButtonGroup);
            app.Button_13.Text = '~';
            app.Button_14 = uiradiobutton(app.AgeButtonGroup);
            app.Button_14.Text = '>=';
            app.Button_15 = uiradiobutton(app.AgeButtonGroup);
            app.Button_15.Text = '<=';
            app.Button_16 = uiradiobutton(app.AgeButtonGroup);
            app.Button_16.Text = '=';
            app.Button_16.Value = true;
            app.Button_17 = uiradiobutton(app.AgeButtonGroup);
            app.Button_17.Text = '>';
            app.Button_18 = uiradiobutton(app.AgeButtonGroup);
            app.Button_18.Text = '<';
            app.DateButtonGroup = uibuttongroup(app.KaytlarGstermePanel);
            app.DateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupsSelectionChanged, true);
            app.DateButtonGroup.Visible = 'off';
            app.DateButtonGroup.Tag = 'Datebg';
            app.Button_7 = uiradiobutton(app.DateButtonGroup);
            app.Button_7.Text = '~';
            app.Button_8 = uiradiobutton(app.DateButtonGroup);
            app.Button_8.Text = '>=';
            app.Button_9 = uiradiobutton(app.DateButtonGroup);
            app.Button_9.Text = '<=';
            app.Button_10 = uiradiobutton(app.DateButtonGroup);
            app.Button_10.Text = '=';
            app.Button_10.Value = true;
            app.Button_11 = uiradiobutton(app.DateButtonGroup);
            app.Button_11.Text = '>';
            app.Button_12 = uiradiobutton(app.DateButtonGroup);
            app.Button_12.Text = '<';
            app.MenPanel = uipanel(app.UIFigure);
            app.MenPanel.Title = 'Menü';
            app.KaytlarGsterButton = uibutton(app.MenPanel, 'push');
            app.KaytlarGsterButton.ButtonPushedFcn = createCallbackFcn(app, @KaytlarGsterButtonPushed, true);
            app.KaytlarGsterButton.Text = 'Kayıtları Göster';
            app.YaHesaplaButton = uibutton(app.MenPanel, 'push');
            app.YaHesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @YaHesaplaButtonPushed, true);
            app.YaHesaplaButton.Text = 'Yaş Hesapla';
            app.KaytEkleButton = uibutton(app.MenPanel, 'push');
            app.KaytEkleButton.ButtonPushedFcn = createCallbackFcn(app, @KaytEkleButtonPushed, true);
            app.KaytEkleButton.Text = 'Kayıt Ekle';
            app.KaytGncelleButton = uibutton(app.MenPanel, 'push');
            app.KaytGncelleButton.ButtonPushedFcn = createCallbackFcn(app, @KaytGncelleButtonPushed, true);
            app.KaytGncelleButton.Text = 'Kayıt Güncelle';
            app.KaytSilButton = uibutton(app.MenPanel, 'push');
            app.KaytSilButton.ButtonPushedFcn = createCallbackFcn(app, @KaytSilButtonPushed, true);
            app.KaytSilButton.Text = 'Kayıt Sil';
            app.BalantLamp = uilamp(app.UIFigure);
            app.BalanButton = uibutton(app.UIFigure, 'push');
            app.BalanButton.ButtonPushedFcn = createCallbackFcn(app, @BalanButtonPushed, true);
            app.BalanButton.Text = 'Bağlan';
            app.KapatButton = uibutton(app.UIFigure, 'push');
            app.KapatButton.ButtonPushedFcn = createCallbackFcn(app, @KapatButtonPushed, true);
            app.KapatButton.Text = 'Kapat';
            app.MenyeDnButton = uibutton(app.UIFigure, 'push');
            app.MenyeDnButton.ButtonPushedFcn = createCallbackFcn(app, @MenyeDnButtonPushed, true);
            app.MenyeDnButton.Text = 'Menüye Dön';
            app.YasHesaplamaProjesiPanel = uipanel(app.UIFigure);
            app.YasHesaplamaProjesiPanel.Title = 'Yas Hesaplama Projesi';
            app.GiriButton = uibutton(app.YasHesaplamaProjesiPanel, 'push');
            app.GiriButton.ButtonPushedFcn = createCallbackFcn(app, @GiriButtonPushed, true);
            app.GiriButton.Text = 'Giriş';
            app.YkleniyorGaugeLabel = uilabel(app.YasHesaplamaProjesiPanel);
            app.YkleniyorGaugeLabel.Visible = 'off';
            app.YkleniyorGaugeLabel.Text = 'Yükleniyor...';
            app.YkleniyorGauge = uigauge(app.YasHesaplamaProjesiPanel, 'circular');
            app.YkleniyorGauge.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YasHesaplama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end